classdef AppTrabalho1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trabalho1Am2                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DadosPVI                       matlab.ui.container.Panel
        ftyLabel                       matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        aLabel                         matlab.ui.control.Label
        eA                             matlab.ui.control.NumericEditField
        eB                             matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        eN                             matlab.ui.control.NumericEditField
        nLabel                         matlab.ui.control.Label
        y0Label                        matlab.ui.control.Label
        eY0                            matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        MetodosGroup                   matlab.ui.container.ButtonGroup
        EulerButton                    matlab.ui.control.RadioButton
        RK2Button                      matlab.ui.control.RadioButton
        RK4Button                      matlab.ui.control.RadioButton
        DormandPrinceButton            matlab.ui.control.RadioButton
        Ode45Button                    matlab.ui.control.RadioButton
        EulerMButton                   matlab.ui.control.RadioButton
        TodososMtodosButton            matlab.ui.control.RadioButton
        bAtualizar                     matlab.ui.control.Button
        TopPanel                       matlab.ui.container.Panel
        PanelGraficos                  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        PanelSobreNos                  matlab.ui.container.Panel
        NunoSantosN2019110035Label     matlab.ui.control.Label
        JooAlmeidaN2020144466Label     matlab.ui.control.Label
        PedroNogueiraN2020136533Label  matlab.ui.control.Label
        VoltarButton                   matlab.ui.control.Button
        Image_5                        matlab.ui.control.Image
        Image_6                        matlab.ui.control.Image
        Image_7                        matlab.ui.control.Image
        Menu_2                         matlab.ui.container.Menu
        RelatrioMenu_2                 matlab.ui.container.Menu
        FecharMenu                     matlab.ui.container.Menu
        SobreNsMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app);
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função de t e y');
            testeN = MException('Matlab:BadNreal','Introduza um número real');
            
            if(app.PanelSobreNos.Visible == true)
                app.PanelSobreNos.Visible = false;
                app.PanelGraficos.Visible = true;
            end
   
            try
                try
                    f(t,y);
                    app.eF.BackgroundColor = 'w'
                catch
                    app.eF.BackgroundColor = 'r'
                    throw(testeF);
                end
                
                if ~isreal(n)
                    app.eN.BackgroundColor = 'r'
                    throw(testeN)
                else
                    app.eN.BackgroundColor = 'w'
                end
                
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")
                legend(app.UIAxes,'Exata')
                escolha = app.MetodosGroup.SelectedObject.Text;
                
                switch escolha
                    case 'Euler'
                        yEuler = MEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        T = array2table(tabela);
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'t','Exata','Euler','erroEuler'};
                        app.UIAxes.Title.String = "Método de Euler";
                        plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                        hold(app.UIAxes,"off")
                        grid(app.UIAxes,"on")
                        legend(app.UIAxes,'Exata','Euler')
                    case 'EulerM'
                        % warndlg('Método não implementado', 'AVISO','modal')
                        yEulerM = MEulerMelh(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        T = array2table(tabela);
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'t','Exata','EulerM','erroEulerM'};
                        app.UIAxes.Title.String = "Método de EulerMelhorado";
                        plot(app.UIAxes,t,yEulerM,"Color","#111111","Marker","+")
                        hold(app.UIAxes,"off")
                        grid(app.UIAxes,"on")
                        legend(app.UIAxes,'Exata','EulerM')
                    case 'RK2'
                        yRK2 = RK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        T = array2table(tabela);
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'t','Exata','RK2','erroRK2'};
                        app.UIAxes.Title.String = "Método de RK2";
                        plot(app.UIAxes,t,yRK2,"Color","#8B0000","Marker",">")
                        hold(app.UIAxes,"off")
                        grid(app.UIAxes,"on")
                        legend(app.UIAxes,'Exata','RK2')
                    case 'RK4'
                        yRK4 = RK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        T = array2table(tabela);
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'t','Exata','RK4','erroRK4'};
                        app.UIAxes.Title.String = "Método de RK4";
                        plot(app.UIAxes,t,yRK4,"Color","#2F4F4F","Marker","pentagram")
                        hold(app.UIAxes,"off")
                        grid(app.UIAxes,"on")
                        legend(app.UIAxes,'Exata','RK4')
                    case 'Ode45'
                        %warndlg('Método não implementado', 'AVISO','modal')
                         yOde45 = ODE45(f,a,b,n,y0);
                         erroOde45 = abs(yExata-yOde45);
                         tabela = [t.',yExata.',yOde45.',erroOde45.'];
                         T = array2table(tabela);
                         app.UITable.Data = T;
                         app.UITable.ColumnName = {'t','Exata','ODE45','erroODE45'};
                         app.UIAxes.Title.String = "Método de ODE45";
                         plot(app.UIAxes,t,yOde45,"Color","#006400","Marker","square")
                         hold(app.UIAxes,"off")
                         grid(app.UIAxes,"on")
                         legend(app.UIAxes,'Exata','ODE45')
                    case 'Dormand Prince'
                        %warndlg('Método não implementado', 'AVISO','modal')
                         yDormandPrince = DormandPrince(f,a,b,n,y0);
                         erroDormandPrince = abs(yExata-yDormandPrince);
                         tabela = [t.',yExata.',yDormandPrince.',erroDormandPrince.'];
                         T = array2table(tabela);
                         app.UITable.Data = T;
                         app.UITable.ColumnName = {'t','Exata','DormandPrince','erroDormandPrince'};
                         app.UIAxes.Title.String = "Método de Dormand Prince";
                         plot(app.UIAxes,t,yDormandPrince,"Color","#B8860B","Marker","diamond")
                         hold(app.UIAxes,"off")
                         grid(app.UIAxes,"on")
                         legend(app.UIAxes,'Exata','DormandPrince')
                    case 'Todos os Métodos'
                        yEuler = MEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        yEulerM = MEulerMelh(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        yRK2 = RK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2); 
                        yRK4 = RK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        yOde45 = ODE45(f,a,b,n,y0);
                        erroOde45 = abs(yExata-yOde45);
                        yDormandPrince = DormandPrince(f,a,b,n,y0);
                        erroDormandPrince = abs(yExata-yDormandPrince);
                        tabela = [t.',yExata.',yEuler.',erroEuler.',yEulerM.',erroEulerM.',yRK2.',erroRK2.',yRK4.',erroRK4.',yOde45.',erroOde45.',yDormandPrince.',erroDormandPrince.'];
                        T = array2table(tabela);
                        app.UITable.Data = T;
                        app.UIAxes.Title.String = "Todos os Métodos";
                        app.UITable.ColumnName = {'t','Exata','Euler','erroEuler','EulerM','erroEulerM','RK2','erroRK2','RK4','erroRK4','ODE45','erroODE45','DormandPrince','erroDormandPrince'};
                        plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                        plot(app.UIAxes,t,yEulerM,"Color",'#111111',"Marker","+")
                        plot(app.UIAxes,t,yRK2,"Color",'#8B0000',"Marker",">")
                        plot(app.UIAxes,t,yRK4,"Color",'#2F4F4F',"Marker","pentagram")
                        plot(app.UIAxes,t,yOde45,"Color",'#006400',"Marker","square")
                        plot(app.UIAxes,t,yDormandPrince,"Color",'#B8860B',"Marker","diamond")
                        hold(app.UIAxes,"off")
                        grid(app.UIAxes,"on")
                        legend(app.UIAxes,'Exata','Euler','EulerM','RK2','RK4','ODE45','DormandPrince')
                end
            catch ME
                errordlg(ME.message,"Erro","modal")
            end
            
        end

        % Callback function
        function ProblemaDropDownValueChanged(app, event)
   
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            app.PanelSobreNos.Visible = false;
            app.PanelGraficos.Visible = true;
        end

        % Menu selected function: SobreNsMenu
        function SobreNsMenuSelected(app, event)
            app.PanelGraficos.Visible = false;
            app.PanelSobreNos.Visible = true;
        end

        % Menu selected function: RelatrioMenu_2
        function RelatrioMenu_2Selected(app, event)
            winopen('Relatorio_AMII_Atividade.pdf')
        end

        % Menu selected function: FecharMenu
        function FecharMenuSelected(app, event)
             SN = questdlg('Deseja mesmo fechar a aplicação?','SAIR','Sim','Não','Não');
            if strcmp(SN,'Sim')
                delete(app.Trabalho1Am2)
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.PanelSobreNos.Visible == true)
                app.PanelSobreNos.Visible = false;
                app.PanelGraficos.Visible = true;
            end
            app.eF.BackgroundColor = "1.00,1.00,1.00";
            app.eF.FontColor = "0.00,0.00,0.00";
            app.eF.Value = '-2*t*y';
            eA = double(0);
            app.eA.Value = eA;
            eB = double(1.5);
            app.eB.Value = eB
            eN = double(10);
            app.eN.Value = eN;
           
            ey0 = double(2);
            app.eY0.Value = ey0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trabalho1Am2.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {662, 662};
                app.GridLayout.ColumnWidth = {'1x'};
                app.TopPanel.Layout.Row = 2;
                app.TopPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {306, '1x'};
                app.TopPanel.Layout.Row = 1;
                app.TopPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trabalho1Am2 = uifigure('Visible', 'off');
            app.Trabalho1Am2.Name = 'AM2 Trabalho 1';
            app.Trabalho1Am2.Icon = 'Icon.png';
            app.Trabalho1Am2.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trabalho1Am2);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.nLabel = uilabel(app.DadosPVI);
            app.nLabel.Text = 'n';
            app.y0Label = uilabel(app.DadosPVI);
            app.y0Label.Text = 'y0';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.Button = uibutton(app.DadosPVI, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'loading.png';
            app.Button.Text = '';
            app.MetodosGroup = uibuttongroup(app.LeftPanel);
            app.MetodosGroup.Title = 'Métodos';
            app.EulerButton = uiradiobutton(app.MetodosGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uiradiobutton(app.MetodosGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.MetodosGroup);
            app.RK4Button.Text = 'RK4';
            app.DormandPrinceButton = uiradiobutton(app.MetodosGroup);
            app.DormandPrinceButton.Text = 'Dormand Prince';
            app.Ode45Button = uiradiobutton(app.MetodosGroup);
            app.Ode45Button.Text = 'Ode45';
            app.EulerMButton = uiradiobutton(app.MetodosGroup);
            app.EulerMButton.Text = 'EulerM';
            app.TodososMtodosButton = uiradiobutton(app.MetodosGroup);
            app.TodososMtodosButton.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.TopPanel = uipanel(app.GridLayout);
            app.PanelGraficos = uipanel(app.TopPanel);
            app.UITable = uitable(app.PanelGraficos);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.PanelGraficos);
            title(app.UIAxes, {'Gráfico para EDO'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PanelSobreNos = uipanel(app.TopPanel);
            app.PanelSobreNos.Visible = 'off';
            app.NunoSantosN2019110035Label = uilabel(app.PanelSobreNos);
            app.NunoSantosN2019110035Label.Text = {'Nuno Santos'; 'Nº2019110035'};
            app.JooAlmeidaN2020144466Label = uilabel(app.PanelSobreNos);
            app.JooAlmeidaN2020144466Label.Text = {'João Almeida'; 'Nº2020144466'};
            app.PedroNogueiraN2020136533Label = uilabel(app.PanelSobreNos);
            app.PedroNogueiraN2020136533Label.Text = {'Pedro Nogueira'; ' Nº2020136533'};
            app.VoltarButton = uibutton(app.PanelSobreNos, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Icon = 'return.png';
            app.VoltarButton.Text = 'Voltar';
            app.Image_5 = uiimage(app.PanelSobreNos);
            app.Image_5.ImageSource = 'Pedro Nogueira.jpg';
            app.Image_6 = uiimage(app.PanelSobreNos);
            app.Image_6.ImageSource = 'Nuno.png';
            app.Image_7 = uiimage(app.PanelSobreNos);
            app.Image_7.ImageSource = 'joaoalmeida.jpg';
            app.Menu_2 = uimenu(app.Trabalho1Am2);
            app.Menu_2.Text = 'Menu';
            app.RelatrioMenu_2 = uimenu(app.Menu_2);
            app.RelatrioMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenu_2Selected, true);
            app.RelatrioMenu_2.Text = 'Relatório';
            app.FecharMenu = uimenu(app.Menu_2);
            app.FecharMenu.MenuSelectedFcn = createCallbackFcn(app, @FecharMenuSelected, true);
            app.FecharMenu.Text = 'Fechar';
            app.SobreNsMenu = uimenu(app.Trabalho1Am2);
            app.SobreNsMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreNsMenuSelected, true);
            app.SobreNsMenu.Text = 'Sobre Nós';
            % Show the figure after all components are created
            app.Trabalho1Am2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTrabalho1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trabalho1Am2)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trabalho1Am2)
        end
    end
end