classdef AppTrabalho2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trabalho1Am2                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DadosPVI                       matlab.ui.container.Panel
        ftuvLabel                      matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        gtuvLabel                      matlab.ui.control.Label
        eG                             matlab.ui.control.EditField
        IntervaloPanel                 matlab.ui.container.Panel
        LabelA                         matlab.ui.control.Label
        eA                             matlab.ui.control.NumericEditField
        LabelB                         matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        CondiesIniciaisPanel           matlab.ui.container.Panel
        LabelV0                        matlab.ui.control.Label
        eV0                            matlab.ui.control.NumericEditField
        u0Label                        matlab.ui.control.Label
        eU0                            matlab.ui.control.NumericEditField
        NdeIteraesPanel                matlab.ui.container.Panel
        eN                             matlab.ui.control.NumericEditField
        LabelN                         matlab.ui.control.Label
        MetodosGroup                   matlab.ui.container.ButtonGroup
        EulerButton                    matlab.ui.control.RadioButton
        RK2Button                      matlab.ui.control.RadioButton
        RK4Button                      matlab.ui.control.RadioButton
        EulerMButton                   matlab.ui.control.RadioButton
        TodososMtodosButton            matlab.ui.control.RadioButton
        bAtualizar                     matlab.ui.control.Button
        ProblemasDropDownLabel         matlab.ui.control.Label
        ProblemasDropDown              matlab.ui.control.DropDown
        TopPanel                       matlab.ui.container.Panel
        PanelGraficos                  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        PanelSobreNos                  matlab.ui.container.Panel
        NunoSantosN2019110035Label     matlab.ui.control.Label
        JooAlmeidaN2020144466Label     matlab.ui.control.Label
        PedroNogueiraN2020136533Label  matlab.ui.control.Label
        VoltarButton                   matlab.ui.control.Button
        Image_5                        matlab.ui.control.Image
        Image_6                        matlab.ui.control.Image
        Image_7                        matlab.ui.control.Image
        Menu_2                         matlab.ui.container.Menu
        RelatrioMenu_2                 matlab.ui.container.Menu
        FecharMenu                     matlab.ui.container.Menu
        SobreNsMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             bAtualizarButtonPushed(app);
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            
            syms u(t) v(t)
            
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;


            testeF = MException('Matlab:BadFunction','Introduza uma função de t,u,v');
            testeN = MException('Matlab:BadFunction','Introduza um número real');


            if(app.PanelSobreNos.Visible == true)
                app.PanelSobreNos.Visible = false;
                app.PanelGraficos.Visible = true;
            end
            try
                try
                    f(t,u,v);
                    app.eF.BackgroundColor = 'w';
                catch
                    app.eF.BackgroundColor = 'r';
                    throw(testeF)
                end
                
                try
                    g(t,u,v);
                    app.eG.BackgroundColor = 'w';
                catch
                    app.eG.BackgroundColor = 'r';
                    throw(testeF)
                end
                if ~isreal(n)
                    app.eN.BackgroundColor = 'r';
                    throw(testeN)
                else
                    app.eN.BackgroundColor = 'w';
                end
                
                if (a>b)
                    throw(testeA);
                end
                
                nlinear = 0;
                try
                    c = subs(str2sym(strF));
                    d = subs(str2sym(strG));
                catch ME
                    nlinear = 1;
%                     msgbox("Não foi possivel calcular a solução exata");
                end
             
                if(nlinear == 0)
                    eqns = [diff(u,t)==c,diff(v,t)==d];
                    ci = [u(0)==u0,v(0)==v0]; 
                    [sExataU,~] = dsolve(eqns,ci);
                    h = @(t) eval(vectorize(char(sExataU)));
                    
                    [t,yEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                    [~,yRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                    [~,yEulerM,~] = NEulerMelhSED(f,g,a,b,n,u0,v0);
                    [~,yRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                    
                    yExata = h(t);
                    
                    erroEuler = abs(yExata-yEuler);
                    erroRK2 = abs(yExata-yRK2);
                    erroEulerM = abs(yExata-yEulerM);
                    erroRK4 = abs(yExata-yRK4);
                    
                    plot(app.UIAxes,t,yExata,"-ro")
                    hold(app.UIAxes,"on")
                    legend(app.UIAxes,'Exata')
                    
                else
                    [t,yEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                    [~,yRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                    [~,yEulerM,~] = NEulerMelhSED(f,g,a,b,n,u0,v0);
                    [~,yRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                end

                escolha = app.MetodosGroup.SelectedObject.Text;
                switch escolha
                    case 'Euler'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yEuler.',erroEuler.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','Euler','erroEuler'};
                            app.UIAxes.Title.String = "Métodos de euler";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','Euler')
                        else
                            tabela = [t.',yEuler.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Euler'};
                            app.UIAxes.Title.String = "Método de Euler";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Euler')   
                        end 
                        case 'EulerM'
                            if(nlinear == 0)
                                tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                                T = array2table(tabela);
                                app.UITable.Data = T;
                                app.UITable.ColumnName = {'t','Exata','EulerM','erroEulerM'};
                                app.UIAxes.Title.String = "Método de Euler Melhorado";
                                plot(app.UIAxes,t,yEulerM,"Color","#111111","Marker","+")
                                hold(app.UIAxes,"off")
                                grid(app.UIAxes,"on")
                                legend(app.UIAxes,'Exata','EulerM')
                            else
                                tabela = [t.',yEulerM.'];
                                T = array2table(tabela);
                                app.UITable.Data = T;
                                app.UITable.ColumnName = {'t','EulerM'};
                                app.UIAxes.Title.String = "Método de Euler Melhorado";
                                plot(app.UIAxes,t,yEulerM,"Color","#111111","Marker","+")
                                hold(app.UIAxes,"off")
                                grid(app.UIAxes,"on")
                                legend(app.UIAxes,'EulerM')
                            end
                        
                    case 'RK2'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yRK2.',erroRK2.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','RK2','erroRK2'};
                            app.UIAxes.Title.String = "Método de RK2";
                            plot(app.UIAxes,t,yRK2,"Color","#EFC94C","Marker",">")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','RK2')
                        else
                            tabela = [t.',yRK2.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','RK2'};
                            app.UIAxes.Title.String = "Método de RK2";
                            plot(app.UIAxes,t,yRK2,"Color","#EFC94C","Marker",">")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'RK2')
                        end
                        
                        
                    case 'RK4'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yRK4.',erroRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','RK4','erroRK4'};
                            app.UIAxes.Title.String = "Método de RK4";
                            plot(app.UIAxes,t,yRK4,"Color","#2F4F4F","Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','RK4')
                        else
                            tabela = [t.',yRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','RK4'};
                            app.UIAxes.Title.String = "Método de RK4";
                            plot(app.UIAxes,t,yRK4,"Color","#2F4F4F","Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'RK4')
                            
                        end
                       
                    case 'Todos os Métodos'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yEuler.',erroEuler.',yEulerM.',erroEulerM.',yRK2.',erroRK2.',yRK4.',erroRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            hold(app.UIAxes,"on")
                            app.UITable.ColumnName = {'t','Exata','Euler','erroEuler','EulerM','erroEulerM','RK2','erroRK2','RK4','erroRK4'};
                            app.UIAxes.Title.String = "Todos os Métodos";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            plot(app.UIAxes,t,yEulerM,"Color",'#111111',"Marker","+")
                            plot(app.UIAxes,t,yRK2,"Color",'#EFC94C',"Marker",">")
                            plot(app.UIAxes,t,yRK4,"Color",'#2F4F4F',"Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','Euler','EulerM','RK2','RK4')
                        else
                            tabela = [t.',yEuler.',yEulerM.',yRK2.',yRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            hold(app.UIAxes,"on")
                            app.UITable.ColumnName = {'t','Euler','EulerM','RK2','RK4'};
                            app.UIAxes.Title.String = "Todos os Métodos";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            plot(app.UIAxes,t,yEulerM,"Color",'#111111',"Marker","+")
                            plot(app.UIAxes,t,yRK2,"Color",'#EFC94C',"Marker",">")
                            plot(app.UIAxes,t,yRK4,"Color",'#2F4F4F',"Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Euler','EulerM','RK2','RK4')
                        end  
                end
            catch ME
                errordlg(ME.message,"Erro","modal")
            end
            
        end

        % Callback function
        function ProblemaDropDownValueChanged(app, event)
            
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            app.PanelSobreNos.Visible = false;
            app.PanelGraficos.Visible = true;
        end

        % Menu selected function: SobreNsMenu
        function SobreNsMenuSelected(app, event)
            app.PanelGraficos.Visible = false;
            app.PanelSobreNos.Visible = true;
        end

        % Menu selected function: RelatrioMenu_2
        function RelatrioMenu_2Selected(app, event)
            winopen('Relatorio_Atividade2.pdf')
        end

        % Menu selected function: FecharMenu
        function FecharMenuSelected(app, event)
            SN = questdlg('Deseja mesmo fechar a aplicação?','SAIR','Sim','Não','Não');
            if strcmp(SN,'Sim')
                delete(app.Trabalho1Am2)
            end
        end

        % Value changed function: ProblemasDropDown
        function ProblemasDropDownValueChanged(app, event)
            problema = app.ProblemasDropDown.Value;
            
            switch problema
                case 'Pêndulo'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-sin(u)-0.3*v';
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.eU0.Value = pi/2;
                    app.eV0.Value = 0;
                case 'Sistema Mola-Massa S/Amortecimento'
                    if(app.eF.Editable==true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-16*u';
                    app.eA.Value = 0;
                    app.eB.Value = 10;
                    app.eN.Value = 100;
                    app.eU0.Value = 9;
                    app.eV0.Value = 0;
                case 'Sistema Mola-Massa C/Amortecimento'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-10*v-25*u';
                    app.eA.Value = 0;
                    app.eB.Value = 2;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = -4;
                case 'Modelo Vibratório Mecânico'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                        app.Button.Visible = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-2*v-2*u+4*cos(t)+2*sin(t)';
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = 3;
                    
                case 'Circuitos Elétricos'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-5*v-4*u+2*exp(-t/4)';
                    app.eA.Value = 0;
                    app.eB.Value = 10;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = 0;
                case 'Outro'
                    if(app.eF.Editable == false)
                        app.eF.Editable = true;
                        app.eG.Editable = true;
                        app.eA.Editable = true;
                        app.eB.Editable = true;
                        app.eN.Editable = true;
                        app.eU0.Editable = true;
                        app.eV0.Editable = true;
                    end
                    
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trabalho1Am2.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {662, 662};
                app.GridLayout.ColumnWidth = {'1x'};
                app.TopPanel.Layout.Row = 2;
                app.TopPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {306, '1x'};
                app.TopPanel.Layout.Row = 1;
                app.TopPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trabalho1Am2 = uifigure('Visible', 'off');
            app.Trabalho1Am2.Name = 'AM2 Trabalho 2';
            app.Trabalho1Am2.Icon = 'Icon.png';
            app.Trabalho1Am2.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trabalho1Am2);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Sistema de Equações Diferenciais';
            app.ftuvLabel = uilabel(app.DadosPVI);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.gtuvLabel = uilabel(app.DadosPVI);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.Editable = 'off';
            app.eG.Value = '-sin(u) - 0.3*v';
            app.IntervaloPanel = uipanel(app.DadosPVI);
            app.IntervaloPanel.Title = 'Intervalo';
            app.LabelA = uilabel(app.IntervaloPanel);
            app.LabelA.Text = 'a =';
            app.eA = uieditfield(app.IntervaloPanel, 'numeric');
            app.eA.Editable = 'off';
            app.LabelB = uilabel(app.IntervaloPanel);
            app.LabelB.Text = 'b =';
            app.eB = uieditfield(app.IntervaloPanel, 'numeric');
            app.eB.Editable = 'off';
            app.eB.Value = 15;
            app.CondiesIniciaisPanel = uipanel(app.DadosPVI);
            app.CondiesIniciaisPanel.Title = 'Condições Iniciais';
            app.LabelV0 = uilabel(app.CondiesIniciaisPanel);
            app.LabelV0.Text = 'v0 =';
            app.eV0 = uieditfield(app.CondiesIniciaisPanel, 'numeric');
            app.eV0.Editable = 'off';
            app.u0Label = uilabel(app.CondiesIniciaisPanel);
            app.u0Label.Text = 'u0 =';
            app.eU0 = uieditfield(app.CondiesIniciaisPanel, 'numeric');
            app.eU0.Editable = 'off';
            app.eU0.Value = 1.5707963267949;
            app.NdeIteraesPanel = uipanel(app.DadosPVI);
            app.NdeIteraesPanel.Title = 'Nº de Iterações';
            app.eN = uieditfield(app.NdeIteraesPanel, 'numeric');
            app.eN.Limits = [0 Inf];
            app.eN.Editable = 'off';
            app.eN.Value = 100;
            app.LabelN = uilabel(app.NdeIteraesPanel);
            app.LabelN.Text = 'n =';
            app.MetodosGroup = uibuttongroup(app.LeftPanel);
            app.MetodosGroup.Title = 'Métodos';
            app.EulerButton = uiradiobutton(app.MetodosGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uiradiobutton(app.MetodosGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.MetodosGroup);
            app.RK4Button.Text = 'RK4';
            app.EulerMButton = uiradiobutton(app.MetodosGroup);
            app.EulerMButton.Text = 'EulerM';
            app.TodososMtodosButton = uiradiobutton(app.MetodosGroup);
            app.TodososMtodosButton.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ProblemasDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemasDropDownLabel.Text = 'Problemas ->';
            app.ProblemasDropDown = uidropdown(app.LeftPanel);
            app.ProblemasDropDown.Items = {'Pêndulo', 'Sistema Mola-Massa S/Amortecimento', 'Sistema Mola-Massa C/Amortecimento', 'Modelo Vibratório Mecânico', 'Circuitos Elétricos', 'Outro'};
            app.ProblemasDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasDropDownValueChanged, true);
            app.ProblemasDropDown.Value = 'Pêndulo';
            app.TopPanel = uipanel(app.GridLayout);
            app.PanelGraficos = uipanel(app.TopPanel);
            app.UITable = uitable(app.PanelGraficos);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.PanelGraficos);
            title(app.UIAxes, {'Métodos Numéricos para SED/PVI'; ''; ''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PanelSobreNos = uipanel(app.TopPanel);
            app.PanelSobreNos.Visible = 'off';
            app.NunoSantosN2019110035Label = uilabel(app.PanelSobreNos);
            app.NunoSantosN2019110035Label.Text = {'Nuno Santos'; 'Nº2019110035'};
            app.JooAlmeidaN2020144466Label = uilabel(app.PanelSobreNos);
            app.JooAlmeidaN2020144466Label.Text = {'João Almeida'; 'Nº2020144466'};
            app.PedroNogueiraN2020136533Label = uilabel(app.PanelSobreNos);
            app.PedroNogueiraN2020136533Label.Text = {'Pedro Nogueira'; ' Nº2020136533'};
            app.VoltarButton = uibutton(app.PanelSobreNos, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Icon = 'return.png';
            app.VoltarButton.Text = 'Voltar';
            app.Image_5 = uiimage(app.PanelSobreNos);
            app.Image_5.ImageSource = 'Pedro Nogueira.jpg';
            app.Image_6 = uiimage(app.PanelSobreNos);
            app.Image_6.ImageSource = 'Nuno.png';
            app.Image_7 = uiimage(app.PanelSobreNos);
            app.Image_7.ImageSource = 'joaoalmeida.jpg';
            app.Menu_2 = uimenu(app.Trabalho1Am2);
            app.Menu_2.Text = 'Menu';
            app.RelatrioMenu_2 = uimenu(app.Menu_2);
            app.RelatrioMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenu_2Selected, true);
            app.RelatrioMenu_2.Text = 'Relatório';
            app.FecharMenu = uimenu(app.Menu_2);
            app.FecharMenu.MenuSelectedFcn = createCallbackFcn(app, @FecharMenuSelected, true);
            app.FecharMenu.Text = 'Fechar';
            app.SobreNsMenu = uimenu(app.Trabalho1Am2);
            app.SobreNsMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreNsMenuSelected, true);
            app.SobreNsMenu.Text = 'Sobre Nós';
            % Show the figure after all components are created
            app.Trabalho1Am2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTrabalho2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trabalho1Am2)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trabalho1Am2)
        end
    end
end