classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaClculoDiferencialeIntegralUIFigure  matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AbrirPDFMenu                   matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        SobreNsMenu                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        tgFrVr                         matlab.ui.container.Tab
        pDados                         matlab.ui.container.Panel
        CalculoDiferencialeintegralPanel  matlab.ui.container.Panel
        fxLabel                        matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        aLabel                         matlab.ui.control.Label
        eA                             matlab.ui.control.NumericEditField
        eH                             matlab.ui.control.NumericEditField
        hLabel                         matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        bAtualizar                     matlab.ui.control.Button
        DerivaesNumricasButtonGroup_2  matlab.ui.container.ButtonGroup
        DiferenasProgressivas3PontosButton  matlab.ui.control.RadioButton
        DiferenasProgressivas2PontosButton  matlab.ui.control.RadioButton
        DiferenasRegressivas3PontosButton  matlab.ui.control.RadioButton
        DiferenasRegressivas2PontosButton_3  matlab.ui.control.RadioButton
        DiferenasCentradas3PontosButton  matlab.ui.control.RadioButton
        IntegraoesNumricasButtonGroup  matlab.ui.container.ButtonGroup
        RegradeSimpsonButton           matlab.ui.control.RadioButton
        RegradosTrapeziosButton        matlab.ui.control.RadioButton
        OquepretendeCalcularSwitchLabel  matlab.ui.control.Label
        OquepretendeCalcularSwitch     matlab.ui.control.Switch
        pResultados                    matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        DerivadasymbolicaLabel         matlab.ui.control.Label
        IntegralsymbolicaLabel         matlab.ui.control.Label
        SegundaDerivadasymbolicaLabel  matlab.ui.control.Label
        ResultadoIntegralAaBLabel      matlab.ui.control.Label
        eIntN                          matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIMostrador_derivada           matlab.ui.control.UIAxes
        UIMostrador_integral           matlab.ui.control.UIAxes
        UIMostrador_2derivada          matlab.ui.control.UIAxes
        tgFr2Vr                        matlab.ui.container.Tab
        pDados_2                       matlab.ui.container.Panel
        FunoRealde2VarveisPanel        matlab.ui.container.Panel
        fxyLabel                       matlab.ui.control.Label
        eF_2                           matlab.ui.control.EditField
        LimitesparaoXPanel             matlab.ui.container.Panel
        eH_2                           matlab.ui.control.NumericEditField
        hLabel_2                       matlab.ui.control.Label
        eB_2                           matlab.ui.control.NumericEditField
        bLabel_2                       matlab.ui.control.Label
        aLabel_2                       matlab.ui.control.Label
        eA_2                           matlab.ui.control.NumericEditField
        LimitesparaoYPanel             matlab.ui.container.Panel
        eH_3                           matlab.ui.control.NumericEditField
        hLabel_3                       matlab.ui.control.Label
        eB_3                           matlab.ui.control.NumericEditField
        bLabel_3                       matlab.ui.control.Label
        aLabel_3                       matlab.ui.control.Label
        eA_3                           matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        bAtualizar_2                   matlab.ui.control.Button
        DerivaesNumricasButtonGroup    matlab.ui.container.ButtonGroup
        DerivadaparcialordemayButton   matlab.ui.control.RadioButton
        DerivadaparcialordemaxButton   matlab.ui.control.RadioButton
        eHarmonica                     matlab.ui.control.EditField
        UIMostrador_2                  matlab.ui.control.UIAxes
        pResultados_2                  matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (diff(f,x,2)+diff(f,y,2)==0)
                YN = 1;
            else
                YN = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RegradosTrapeziosButton.Enable = false;
            app.RegradeSimpsonButton.Enable = false;
            app.ResultadoIntegralAaBLabel.Visible = false;
            app.eIntN.Visible = false;
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value;
            h = app.eH.Value;
            b = app.eB.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x');
            testeAReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB=MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');
            testeAigual = MException('MATLAB:BadFunction','Valor de "a" tem de ser DIFERENTE que "b"');
            
            try 
                dydxSym = diff(f(x));
                sedydxSym = diff(f(x),2);  %calcula s segunda derivada
                g =@(x) eval(vectorize(char(dydxSym)));
                o =@(x) eval(vectorize(char(sedydxSym)));
                
              %Verificações de testes 
              
                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real  
                    app.eA.BackgroundColor = 'r';
                    app.eA.FontColor = "w";
                    throw(testeAReal);
                else
                    app.eA.BackgroundColor = '0.07,0.07,0.07';
                    app.eA.FontColor = "0.73,0.73,0.73";
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB.BackgroundColor = 'r';
                    app.eB.FontColor = "w";
                    throw(testeBReal);
                else
                    app.eB.BackgroundColor = '0.07,0.07,0.07';
                    app.eB.FontColor = "0.73,0.73,0.73";
                end
                if ~(isscalar(h) && isreal(h)) %se h não for um numero ou se não for real
                    app.eH.BackgroundColor = 'r';
                    app.eH.FontColor = "w";
                    throw(testeHReal);
                else
                    app.eH.BackgroundColor = '0.07,0.07,0.07';
                    app.eH.FontColor = "0.73,0.73,0.73";
                end
                
                if(a>b || a==b)
                    app.eA.BackgroundColor = 'r';
                    app.eA.FontColor = "w";
                    app.eB.BackgroundColor = 'r';
                    app.eB.FontColor = "w";
                    if(a == b)
                        throw(testeAigual)
                    end
                    throw(testeAmaiorB);
                else
                    app.eA.BackgroundColor = '0.07,0.07,0.07';
                    app.eA.FontColor = "0.73,0.73,0.73";
                    app.eB.BackgroundColor = '0.07,0.07,0.07';
                    app.eB.FontColor = "0.73,0.73,0.73";
                 end

              %%
                escolhamenu = app.OquepretendeCalcularSwitch.Value;
                escolha1 = app.DerivaesNumricasButtonGroup_2.SelectedObject.Text;
                if(escolhamenu == "Derivações Numéricas")
                    switch escolha1
                        case 'Diferenças Progressivas 2 Pontos'
                            [x,y,dydxDFP] = DiferencasFinitasProgressivas2P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP','erroDFP','2ªDerivada'});
    
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.UIAxes,x,dydxDFP,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.UIAxes,"on")

                            legend(app.UIAxes,'y','dydxExato','dxdyDFP','2ª derivada')
    
    
                        case 'Diferenças Regressivas 2 Pontos'
                            [x,y,dydxDFR] = DiferencasFinitasRegressivas2P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFR = abs(dydxExata -dydxDFR);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFR.',erroDFR.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR','erroDFR','2ªDerivada'});
    
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.UIAxes,x,dydxDFR,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.UIAxes,"on")
    
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Progressivas 3 Pontos'
                            [x,y,dydxDFP] = DiferencasFinitasProgressivas3P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP3','erroDFP3','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.UIAxes,x,dydxDFP,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.UIAxes,"on")
    
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Regressivas 3 Pontos'
                            [x,y,dydxDFR] = DiferencasFinitasRegressivas3P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFR);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFR.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR','erroDFR','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.UIAxes,x,dydxDFR,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.UIAxes,"on")
                            
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Centradas 3 Pontos'
                            [x,y,dydxDFC] = DiferencasFinitasCentradas3P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFC = abs(dydxExata -dydxDFC);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFC.',erroDFC.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFC','erroDCR','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.UIAxes,x,dydxDFC,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.UIAxes,"on")
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFC','2ª derivada')
                    end
                else
                
                
                    n = (b-a)/h;
                    escolha2 = app.IntegraoesNumricasButtonGroup.SelectedObject.Text;
    
                    switch escolha2
                        case 'Regra dos Trapezios'
                            T = RTrapezios(f,a,b,n);
                            app.eIntN.Value = T;
                            [x,y,~] = DiferencasFinitasProgressivas2P(f,a,b,h);
                            area(app.UIAxes,x,y,"FaceColor","b","FaceAlpha",0.2)
                            legend(app.UIAxes,'Area Integral')
                            
                            
                        case 'Regra de Simpson'
                            S = RSimpson(f,a,b,n);
                            app.eIntN.Value = S;
                            [x,y,~] = DiferencasFinitasProgressivas2P(f,a,b,h);
                            area(app.UIAxes,x,y,"FaceColor","b","FaceAlpha",0.2)
                            legend(app.UIAxes,'Area Integral')
                    end
                end
                    sExata = int(f(sym('x')));
                    %q = integral(f,a,b);
    
                    cla(app.UIMostrador_derivada)
                    cla(app.UIMostrador_integral)
                    cla(app.UIMostrador_2derivada)
                    formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                    text(app.UIMostrador_derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\int_a^b',app.eF.Value,'=',latex(sExata),'$'];
                    text(app.UIMostrador_integral,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\frac{d^2y}{dx^2}=',latex(dydxSym),'$'];
                    text(app.UIMostrador_2derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
    
                    hold(app.UIAxes,"off")
    
                catch ME
                    errordlg(ME.message,"ERRO","modal")
            end


        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)

            syms x y f(x,y)

            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));

            partialdydxSymx = diff(f(x,y),x);
            partialdydxSymy = diff(f(x,y),y);

            a = app.eA_2.Value;
            h1 = app.eH_2.Value;
            b = app.eB_2.Value;
            a2 = app.eA_3.Value;
            h2 = app.eH_3.Value;
            b2 = app.eB_3.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x');
            testeAReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB = MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');
            testeAigual = MException('MATLAB:BadFunction','Valor de "a" tem de ser DIFERENTE que "b"');
            try
       %Verificações de testes
                %try
                %  f(sym('x'));
                %catch
                %  throw(testeF);
                % end

                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real
                    app.eA_2.BackgroundColor = 'r';
                    app.eA_2.FontColor = "w";
                    throw(testeAReal);
                else
                    app.eA_2.BackgroundColor = '0.07,0.07,0.07';
                    app.eA_2.FontColor = "0.73,0.73,0.73";
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB_2.BackgroundColor = 'r';
                    app.eB_2.FontColor = "w";
                    throw(testeBReal);
                else
                    app.eB_2.BackgroundColor = '0.07,0.07,0.07';
                    app.eB_2.FontColor = "0.73,0.73,0.73";
                end
                if ~(isscalar(h1) && isreal(h1)) %se h não for um numero ou se não for real
                    app.eH_2.BackgroundColor = 'r';
                    app.eH_2.FontColor = "w";
                    throw(testeHReal);
                else
                    app.eH_2.BackgroundColor = '0.07,0.07,0.07';
                    app.eH_2.FontColor = "0.73,0.73,0.73";
                end

                if(a>b || a==b) % se a>b ou a == b
                    app.eA_2.BackgroundColor = 'r';
                    app.eA_2.FontColor = "w";
                    app.eB_2.BackgroundColor = 'r';
                    app.eB_2.FontColor = "w";
                    
                    if(a == b)
                        throw(testeAigual)
                    end
                    throw(testeAmaiorB);
                else
                    app.eA_2.BackgroundColor = '0.07,0.07,0.07';
                    app.eA_2.FontColor = "0.73,0.73,0.73";
                    app.eB_2.BackgroundColor = '0.07,0.07,0.07';
                    app.eB_2.FontColor = "0.73,0.73,0.73";
                end
                 
              %% 2-Box
              
              if ~(isscalar(a2) && isreal(a2))  %se A não for um numero ou se não for real
                  app.eA_3.BackgroundColor = 'r';
                  app.eA_3.FontColor = "w";
                  throw(testeAReal);
              else
                  app.eA_3.BackgroundColor = '0.07,0.07,0.07';
                  app.eA_3.FontColor = "0.73,0.73,0.73";
              end

              if ~(isscalar(b2) && isreal(b2)) %se B não for um numero ou se não for real
                  app.eB_3.BackgroundColor = 'r';
                  app.eB_3.FontColor = "w";
                  throw(testeBReal);
              else
                  app.eB_3.BackgroundColor = '0.07,0.07,0.07';
                  app.eB_3.FontColor = "0.73,0.73,0.73";
              end
              if ~(isscalar(h2) && isreal(h2)) %se h não for um numero ou se não for real
                  app.eH_3.BackgroundColor = 'r';
                  app.eH_3.FontColor = "w";
                  throw(testeHReal);
              else
                  app.eH_3.BackgroundColor = '0.07,0.07,0.07';
                  app.eH_3.FontColor = "0.73,0.73,0.73";
              end

              if(a2>b2 || a2==b2)
                  app.eA_3.BackgroundColor = 'r';
                  app.eA_3.FontColor = "w";
                  app.eB_3.BackgroundColor = 'r';
                  app.eB_3.FontColor = "w";
                  if(a2 == b2)
                        throw(testeAigual)
                  end
                  throw(testeAmaiorB);
              else
                  app.eA_3.BackgroundColor = '0.07,0.07,0.07';
                  app.eA_3.FontColor = "0.73,0.73,0.73";
                  app.eB_3.BackgroundColor = '0.07,0.07,0.07';
                  app.eB_3.FontColor = "0.73,0.73,0.73";
              end
              
            %%
              [x,y] = meshgrid(a:h1:b, a2:h2:b2);
              z = f(x,y);
              mesh(app.UIAxes_2, x,y,z)

              if isHarmonica(app,f)
                  strHarmonica = "A função f é harmónica";
                  corHarmonica = "g";  %coloca a verde se e harmonica
              else
                  strHarmonica = "A função f não é harmónica";
                  corHarmonica = "r";  %coloca a vermelho se nao
              end
              
              app.eHarmonica.Value = strHarmonica;
              app.eHarmonica.FontColor = corHarmonica;
              
              escolha3 = app.DerivaesNumricasButtonGroup.SelectedObject.Text;
              switch escolha3
                  case 'Derivada parcial ordem a x'
                      cla(app.UIMostrador_2)
                      formaLatex =['$','\frac{\partial f}{\partial x}=\frac{\partial ',strFxy,'}{\partial x}=',latex(partialdydxSymx),'$'];
                      text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                  case 'Derivada parcial ordem a y'
                      cla(app.UIMostrador_2)
                      formaLatex =['$','\frac{\partial f}{\partial y}=\frac{\partial ',strFxy,'}{\partial y}=',latex(partialdydxSymy),'$'];
                      text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
              end
              
              
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
            

        end

        % Value changed function: OquepretendeCalcularSwitch
        function OquepretendeCalcularSwitchValueChanged(app, event)
            value = app.OquepretendeCalcularSwitch.Value;
            if(value == "Derivações Numéricas")
                app.UITable.Visible = true;
                app.DiferenasProgressivas2PontosButton.Enable = true;
                app.DiferenasProgressivas3PontosButton.Enable = true;
                app.DiferenasRegressivas2PontosButton_3.Enable = true;
                app.DiferenasRegressivas3PontosButton.Enable = true;
                app.DiferenasCentradas3PontosButton.Enable = true;
                app.RegradosTrapeziosButton.Enable = false;
                app.RegradeSimpsonButton.Enable = false;
                app.ResultadoIntegralAaBLabel.Visible = false;
                app.eIntN.Visible = false;
            end
            if(value == "Integrações Numéricas")
                app.UITable.Visible = false;
                app.DiferenasProgressivas2PontosButton.Enable = false;
                app.DiferenasProgressivas3PontosButton.Enable = false;
                app.DiferenasRegressivas2PontosButton_3.Enable = false;
                app.DiferenasRegressivas3PontosButton.Enable = false;
                app.DiferenasCentradas3PontosButton.Enable = false;
                app.RegradosTrapeziosButton.Enable = true;
                app.RegradeSimpsonButton.Enable = true;
                app.ResultadoIntegralAaBLabel.Visible = true;
                app.eIntN.Visible = true;
            end
        end

        % Menu selected function: SobreNsMenu
        function SobreNsMenuSelected(app, event)
            run("autores.mlapp")
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            SN = questdlg('Deseja mesmo fechar a aplicação?','SAIR','Sim','Não','Não');
            if strcmp(SN,'Sim')
                delete(app.MquinaClculoDiferencialeIntegralUIFigure)
            end
        end

        % Menu selected function: AbrirPDFMenu
        function AbrirPDFMenuSelected(app, event)
            winopen('Relatorio_atividade03.pdf')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
   
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.eF.BackgroundColor = "0.07,0.07,0.07";
            app.eF.FontColor = "0.73,0.73,0.73";
            app.eA_2.BackgroundColor = "0.07,0.07,0.07";
            app.eA_2.FontColor = "0.73,0.73,0.73";
            app.eB_2.BackgroundColor = "0.07,0.07,0.07";
            app.eB_2.FontColor = "0.73,0.73,0.73";
            app.eH_2.BackgroundColor = "0.07,0.07,0.07";
            app.eH_2.FontColor = "0.73,0.73,0.73";
            %%2 caixa
            app.eA_3.BackgroundColor = "0.07,0.07,0.07";
            app.eA_3.FontColor = "0.73,0.73,0.73";
            app.eH_3.BackgroundColor = "0.07,0.07,0.07";
            app.eH_3.FontColor = "0.73,0.73,0.73";
            app.eB_3.BackgroundColor = "0.07,0.07,0.07";
            app.eB_3.FontColor = "0.73,0.73,0.73";
            
          %Valores
            app.eF.Value = 'x^2+y^2';
            app.eA_2.Value = 0;
            app.eH_2.Value = 0.1;
            app.eB_2.Value = pi*2;
            app.eA_3.Value = 0;
            app.eH_3.Value = 0.1;
            app.eB_3.Value = pi*2;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.eF.BackgroundColor = "0.07,0.07,0.07";
            app.eF.FontColor = "0.73,0.73,0.73";
            app.eA.BackgroundColor = "0.07,0.07,0.07";
            app.eA.FontColor = "0.73,0.73,0.73";
            app.eB.BackgroundColor = "0.07,0.07,0.07";
            app.eB.FontColor = "0.73,0.73,0.73";
            app.eH.BackgroundColor = "0.07,0.07,0.07";
            app.eH.FontColor = "0.73,0.73,0.73";
          %Valores
            app.eF.Value = 'sin(x)';
            app.eA.Value = 0;
            app.eH.Value = 0.1;
            app.eB.Value = pi*2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MquinaClculoDiferencialeIntegralUIFigure = uifigure('Visible', 'off');
            app.MquinaClculoDiferencialeIntegralUIFigure.Name = 'Máquina Cálculo Diferencial e Integral';
            app.Menu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.Menu.Text = 'Menu';
            app.AbrirPDFMenu = uimenu(app.Menu);
            app.AbrirPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirPDFMenuSelected, true);
            app.AbrirPDFMenu.Text = 'Abrir PDF';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SobreNsMenu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.SobreNsMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreNsMenuSelected, true);
            app.SobreNsMenu.Text = 'Sobre Nós';
            app.TabGroup = uitabgroup(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.CalculoDiferencialeintegralPanel = uipanel(app.pDados);
            app.CalculoDiferencialeintegralPanel.Title = 'Calculo Diferencial e integral ';
            app.fxLabel = uilabel(app.CalculoDiferencialeintegralPanel);
            app.fxLabel.Text = 'f(x) =';
            app.eF = uieditfield(app.CalculoDiferencialeintegralPanel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.CalculoDiferencialeintegralPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.CalculoDiferencialeintegralPanel, 'numeric');
            app.eH = uieditfield(app.CalculoDiferencialeintegralPanel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.CalculoDiferencialeintegralPanel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.CalculoDiferencialeintegralPanel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.CalculoDiferencialeintegralPanel);
            app.bLabel.Text = 'b';
            app.Button_2 = uibutton(app.CalculoDiferencialeintegralPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'loading.png';
            app.Button_2.Text = '';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.DerivaesNumricasButtonGroup_2 = uibuttongroup(app.pDados);
            app.DerivaesNumricasButtonGroup_2.Title = 'Derivações Numéricas';
            app.DiferenasProgressivas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasProgressivas3PontosButton.Text = 'Diferenças Progressivas 3 Pontos';
            app.DiferenasProgressivas2PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasProgressivas2PontosButton.Text = 'Diferenças Progressivas 2 Pontos';
            app.DiferenasProgressivas2PontosButton.Value = true;
            app.DiferenasRegressivas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasRegressivas3PontosButton.Text = 'Diferenças Regressivas 3 Pontos';
            app.DiferenasRegressivas2PontosButton_3 = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasRegressivas2PontosButton_3.Text = 'Diferenças Regressivas 2 Pontos';
            app.DiferenasCentradas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasCentradas3PontosButton.Text = 'Diferenças Centradas 3 Pontos';
            app.IntegraoesNumricasButtonGroup = uibuttongroup(app.pDados);
            app.IntegraoesNumricasButtonGroup.Title = 'Integraçoes Numéricas';
            app.RegradeSimpsonButton = uiradiobutton(app.IntegraoesNumricasButtonGroup);
            app.RegradeSimpsonButton.Text = 'Regra de Simpson';
            app.RegradosTrapeziosButton = uiradiobutton(app.IntegraoesNumricasButtonGroup);
            app.RegradosTrapeziosButton.Text = 'Regra dos Trapezios';
            app.RegradosTrapeziosButton.Value = true;
            app.OquepretendeCalcularSwitchLabel = uilabel(app.pDados);
            app.OquepretendeCalcularSwitchLabel.Text = 'O que pretende Calcular?';
            app.OquepretendeCalcularSwitch = uiswitch(app.pDados, 'slider');
            app.OquepretendeCalcularSwitch.Items = {'Derivações Numéricas', 'Integrações Numéricas'};
            app.OquepretendeCalcularSwitch.ValueChangedFcn = createCallbackFcn(app, @OquepretendeCalcularSwitchValueChanged, true);
            app.OquepretendeCalcularSwitch.Value = 'Derivações Numéricas';
            app.pResultados = uipanel(app.tgFrVr);
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DerivadasymbolicaLabel = uilabel(app.pResultados);
            app.DerivadasymbolicaLabel.Text = 'Derivada symbolica:';
            app.IntegralsymbolicaLabel = uilabel(app.pResultados);
            app.IntegralsymbolicaLabel.Text = 'Integral symbolica:';
            app.SegundaDerivadasymbolicaLabel = uilabel(app.pResultados);
            app.SegundaDerivadasymbolicaLabel.Text = 'Segunda Derivada symbolica:';
            app.ResultadoIntegralAaBLabel = uilabel(app.pResultados);
            app.ResultadoIntegralAaBLabel.Text = 'Resultado Integral A a B';
            app.eIntN = uieditfield(app.pResultados, 'numeric');
            app.eIntN.Editable = 'off';
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, {'Gráfico de uma Função Real de Varável Real'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIMostrador_derivada = uiaxes(app.pResultados);
            app.UIMostrador_derivada.XTick = [];
            app.UIMostrador_derivada.YTick = [];
            app.UIMostrador_integral = uiaxes(app.pResultados);
            app.UIMostrador_integral.XTick = [];
            app.UIMostrador_integral.YTick = [];
            app.UIMostrador_2derivada = uiaxes(app.pResultados);
            app.UIMostrador_2derivada.XTick = [];
            app.UIMostrador_2derivada.YTick = [];
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.FunoRealde2VarveisPanel = uipanel(app.pDados_2);
            app.FunoRealde2VarveisPanel.Title = 'Função Real de 2 Varáveis';
            app.fxyLabel = uilabel(app.FunoRealde2VarveisPanel);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.FunoRealde2VarveisPanel, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.LimitesparaoXPanel = uipanel(app.FunoRealde2VarveisPanel);
            app.LimitesparaoXPanel.Title = 'Limites para o X';
            app.eH_2 = uieditfield(app.LimitesparaoXPanel, 'numeric');
            app.eH_2.Value = 0.1;
            app.hLabel_2 = uilabel(app.LimitesparaoXPanel);
            app.hLabel_2.Text = 'h';
            app.eB_2 = uieditfield(app.LimitesparaoXPanel, 'numeric');
            app.eB_2.Value = 6.28318530717959;
            app.bLabel_2 = uilabel(app.LimitesparaoXPanel);
            app.bLabel_2.Text = 'b';
            app.aLabel_2 = uilabel(app.LimitesparaoXPanel);
            app.aLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.LimitesparaoXPanel, 'numeric');
            app.LimitesparaoYPanel = uipanel(app.FunoRealde2VarveisPanel);
            app.LimitesparaoYPanel.Title = 'Limites para o Y';
            app.eH_3 = uieditfield(app.LimitesparaoYPanel, 'numeric');
            app.eH_3.Value = 0.1;
            app.hLabel_3 = uilabel(app.LimitesparaoYPanel);
            app.hLabel_3.Text = 'h';
            app.eB_3 = uieditfield(app.LimitesparaoYPanel, 'numeric');
            app.eB_3.Value = 6.28318530717959;
            app.bLabel_3 = uilabel(app.LimitesparaoYPanel);
            app.bLabel_3.Text = 'b';
            app.aLabel_3 = uilabel(app.LimitesparaoYPanel);
            app.aLabel_3.Text = 'a';
            app.eA_3 = uieditfield(app.LimitesparaoYPanel, 'numeric');
            app.Button = uibutton(app.FunoRealde2VarveisPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'loading.png';
            app.Button.Text = '';
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.DerivaesNumricasButtonGroup = uibuttongroup(app.pDados_2);
            app.DerivaesNumricasButtonGroup.Title = 'Derivações Numéricas';
            app.DerivadaparcialordemayButton = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DerivadaparcialordemayButton.Text = 'Derivada parcial ordem a y';
            app.DerivadaparcialordemaxButton = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DerivadaparcialordemaxButton.Text = 'Derivada parcial ordem a x';
            app.DerivadaparcialordemaxButton.Value = true;
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.eHarmonica.Editable = 'off';
            app.UIMostrador_2 = uiaxes(app.pDados_2);
            app.UIMostrador_2.XTick = [];
            app.UIMostrador_2.YTick = [];
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, {'Gráfico de uma Função Real de 2 Variáveis'; ''})
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.MquinaClculoDiferencialeIntegralUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaClculoDiferencialeIntegralUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaClculoDiferencialeIntegralUIFigure)
        end
    end
end